classdef bladeRF_MIMO_offset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        StartButton                  matlab.ui.control.Button
        timeout_msEditField          matlab.ui.control.NumericEditField
        timeout_msEditFieldLabel     matlab.ui.control.Label
        num_transfersEditField       matlab.ui.control.NumericEditField
        num_transfersEditFieldLabel  matlab.ui.control.Label
        buffer_sizeEditField         matlab.ui.control.NumericEditField
        buffer_sizeEditFieldLabel    matlab.ui.control.Label
        num_buffersEditField         matlab.ui.control.NumericEditField
        num_buffersEditFieldLabel    matlab.ui.control.Label
        VGA2EditField                matlab.ui.control.NumericEditField
        VGA2EditFieldLabel           matlab.ui.control.Label
        VGA1EditField                matlab.ui.control.NumericEditField
        VGA1EditFieldLabel           matlab.ui.control.Label
        LNAEditField                 matlab.ui.control.NumericEditField
        LNAEditFieldLabel            matlab.ui.control.Label
        bandwidthEditField           matlab.ui.control.NumericEditField
        bandwidthEditFieldLabel      matlab.ui.control.Label
        samplerateEditField          matlab.ui.control.NumericEditField
        samplerateEditFieldLabel     matlab.ui.control.Label
        frequencyEditField           matlab.ui.control.NumericEditField
        frequencyEditFieldLabel      matlab.ui.control.Label
        DeviceDropDown               matlab.ui.control.DropDown
        DeviceDropDownLabel          matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        cross_corr                   matlab.ui.control.UIAxes
        rx0                          matlab.ui.control.UIAxes
        rx1                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        device_list % Array of device handles
        curr_device % Description
        flag_running % Indicates if the current device is receiving/transmitting
    end
    
    methods (Access = public)
        function dev = createDevice(app, dev_serial)
            dev = bladeRF_MIMO(dev_serial);
            
            dev.rx.frequency = app.frequencyEditField.Value;
            dev.rx.samplerate = app.samplerateEditField.Value;
            dev.rx.bandwidth = app.bandwidthEditField.Value;
            dev.rx.lna = app.LNAEditField.Value;
            dev.rx.vga1 = app.VGA1EditField.Value;
            dev.rx.vga2 = app.VGA2EditField.Value;
            
            dev.rx.config.num_buffers = app.num_buffersEditField.Value;
            dev.rx.config.buffer_size = app.buffer_sizeEditField.Value;
            dev.rx.config.num_transfers = app.num_transfersEditField.Value;
            dev.rx.config.timeout_ms = app.timeout_msEditField.Value;
        end

        % Instantiate a device handle for each device in the list and
        % maintain a vector of device handles
        % Devices are instantiated with default values from createDevice().
        %
        % TO DO: update when a new device is connected after app
        % initialisation.
        function createDeviceHandles(app)
            devices = bladeRF.devices();
            app.dev_handles = zeros(len(devices), bladeRF_MIMO);

            for i = 1:len(devices)
                dev = createDevice(app, devices(i).serial);
                app.dev_handles.append(dev);
            end
        end

        function updateDeviceHandles(app)
            % Update app.dev_handles
        end
        
        function [signal_i, signal_q] = deinterleave(~, samples)
            signal_i = samples(1:2:end - 1, :)./2048.0;
            signal_q = samples(2:2:end, :)./2048.0;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.frequencyEditField.Value = 2400000;
            app.samplerateEditField.Value = 10e6;
            app.bandwidthEditField.Value = 5e6;
            app.LNAEditField.Value = 3;
            app.VGA1EditField.Value = 8;
            app.VGA2EditField.Value = 5;

            app.num_buffersEditField.Value = 64;
            app.buffer_sizeEditField.Value = 16384;
            app.num_transfersEditField.Value = 16;
            app.timeout_msEditField.Value = 5000;

            app.dev_handles = createDeviceHandles(app);

            app.DeviceDropDown.Placeholder = '<select device>';
        end

        % Drop down opening function: DeviceDropDown
        function DeviceDropDownOpening(app, event)
            % TO DO: Handle case for no connected devices
            app.DeviceDropDown.Items = {app.device_handles(:).info.serial};
            app.DeviceDropDown.ItemsData = app.device_handles;
        end

        % Value changed function: DeviceDropDown
        function SelectDevice(app, event)
            value = app.DeviceDropDown.Value;
            % if currently RX/TX, stop the device cleanly before connecting
            % a new device
            app.curr_device = bladeRF_MIMO(value);

            % Disable if app.flag_running == 1
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Pressing 'start'
            if app.flag_running == 0
                app.flag_running = bitcmp(app.flag_running);

                dev = app.curr_device;
                dev.rx.frequency = app.frequencyEditField.Value;
                dev.rx.samplerate = app.samplerateEditField.Value;
                dev.rx.bandwidth = app.bandwidthEditField.Value;
                dev.rx.lna = app.LNAEditField.Value;
                dev.rx.vga1 = app.VGA1EditField.Value;
                dev.rx.vga2 = app.VGA2EditField.Value;
    
                dev.rx.config.num_buffers = app.num_buffersEditField.Value;
                dev.rx.config.buffer_size = app.buffer_sizeEditField.Value;
                dev.rx.config.num_transfers = app.num_transfersEditField.Value;
                dev.rx.config.timeout_ms = app.timeout_msEditField.Value;
    
                app.flag_running = 1;
                dev.rx.start();
            end

            while app.flag_running == 1
                [samples, timestamp_out, actual_count, overrun] = dev.receive(0.250*dev.rx.samplerate);
            end

            % Pressing 'stop'
            if app.flag_running == 1
                dev.rx.stop();
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {950, 950};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {311, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DeviceDropDownLabel = uilabel(app.LeftPanel);
            app.DeviceDropDownLabel.Text = 'Device';
            app.DeviceDropDown = uidropdown(app.LeftPanel);
            app.DeviceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @DeviceDropDownOpening, true);
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDevice, true);
            app.DeviceDropDown.Placeholder = '<select device>';
            app.frequencyEditFieldLabel = uilabel(app.LeftPanel);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.frequencyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.frequencyEditField.Value = 2400000;
            app.samplerateEditFieldLabel = uilabel(app.LeftPanel);
            app.samplerateEditFieldLabel.Text = 'samplerate';
            app.samplerateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.samplerateEditField.Value = 10000000;
            app.bandwidthEditFieldLabel = uilabel(app.LeftPanel);
            app.bandwidthEditFieldLabel.Text = 'bandwidth';
            app.bandwidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.bandwidthEditField.Value = 5000000;
            app.LNAEditFieldLabel = uilabel(app.LeftPanel);
            app.LNAEditFieldLabel.Text = 'LNA';
            app.LNAEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LNAEditField.Limits = [0 6];
            app.LNAEditField.Value = 3;
            app.VGA1EditFieldLabel = uilabel(app.LeftPanel);
            app.VGA1EditFieldLabel.Text = 'VGA1';
            app.VGA1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.VGA1EditField.Limits = [0 30];
            app.VGA1EditField.Value = 8;
            app.VGA2EditFieldLabel = uilabel(app.LeftPanel);
            app.VGA2EditFieldLabel.Text = 'VGA2';
            app.VGA2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.VGA2EditField.Limits = [0 30];
            app.VGA2EditField.Value = 5;
            app.num_buffersEditFieldLabel = uilabel(app.LeftPanel);
            app.num_buffersEditFieldLabel.Text = 'num_buffers';
            app.num_buffersEditField = uieditfield(app.LeftPanel, 'numeric');
            app.num_buffersEditField.Value = 64;
            app.buffer_sizeEditFieldLabel = uilabel(app.LeftPanel);
            app.buffer_sizeEditFieldLabel.Text = 'buffer_size';
            app.buffer_sizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.buffer_sizeEditField.Value = 16384;
            app.num_transfersEditFieldLabel = uilabel(app.LeftPanel);
            app.num_transfersEditFieldLabel.Text = 'num_transfers';
            app.num_transfersEditField = uieditfield(app.LeftPanel, 'numeric');
            app.num_transfersEditField.Value = 16;
            app.timeout_msEditFieldLabel = uilabel(app.LeftPanel);
            app.timeout_msEditFieldLabel.Text = 'timeout_ms';
            app.timeout_msEditField = uieditfield(app.LeftPanel, 'numeric');
            app.timeout_msEditField.Value = 5000;
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RightPanel = uipanel(app.GridLayout);
            app.rx1 = uiaxes(app.RightPanel);
            title(app.rx1, 'RX1')
            xlabel(app.rx1, 't')
            ylabel(app.rx1, 'Relative amplitude')
            zlabel(app.rx1, 'Z')
            app.rx0 = uiaxes(app.RightPanel);
            title(app.rx0, 'RX0')
            xlabel(app.rx0, 't')
            ylabel(app.rx0, 'Relative amplitude')
            zlabel(app.rx0, 'Z')
            app.cross_corr = uiaxes(app.RightPanel);
            title(app.cross_corr, 'Cross-Correlation')
            xlabel(app.cross_corr, 'Phase offset')
            ylabel(app.cross_corr, 'Amplitude')
            zlabel(app.cross_corr, 'Z')
            app.cross_corr.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bladeRF_MIMO_offset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end